classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Minefield double
        Count double % Description
        MinefieldTable matlab.ui.control.Table
    end

    methods (Access = public)
        
        
        function generateBomb(app, minefield, difficulty)
            [i, j] = size(minefield);
            app.Count = 0;
            for k = 1:difficulty
                randRow = randi([1, i], 1);
                randCol = randi([1, j], 1);
                if minefield(randRow, randCol) == 0
                    minefield(randRow, randCol) = 1;
                    app.Count = app.Count + 1;
                end
            end
            app.MinefieldTable = uitable('Data', minefield, 'FontSize', 18, ...
                'ColumnWidth', {30, 30, 30, 30, 30, 30, 30, 30}, ...
                'BackgroundColor', [0.8 0.8 0.8; 0.8 0.8 0.8], ...
                'ForegroundColor', [0 0 0], ...
                'ColumnName', {}, 'RowName', {}, ...
                'CellEditCallback', @(src, event) updateCellColors(app, src, event), ...
                'CellSelectionCallback', @(src, event) cellSelected(app, src, event));
            
            
        end

        %function updateCellColors(~, ~, event)
            % row = event.Indices(1);
            % col = event.Indices(2);
            % disp(app.MinefieldTable.Data(row,col));

            % if data(row, col) == 1
            %     src.RowStriping = 'off';
            %     src.Data(row, col) = 'X';
            %     src.BackgroundColor(row, col) = [1 0 0];
            %     src.ForegroundColor(row, col) = [1 1 1];
            % end
        %     else
        %         count = countNeighboringMines(app, data, row, col);
        %         if count > 0
        %             src.Data{row, col} = count;
        %             src.BackgroundColor{row, col} = [0.8 0.8 0.8];
        %             src.ForegroundColor{row, col} = [0 0 1];
        %         else
        %             src.Data{row, col} = '';
        %             src.BackgroundColor{row, col} = [0.8 0.8 0.8];
        %             src.ForegroundColor{row, col} = [0 0 0];
        %         end
        %     end
         %end
        
        
         function cellSelected(app, ~, event)
            row = event.Indices(1);
            col = event.Indices(2);
            if app.MinefieldTable.Data(row,col) == 1
                app.MinefieldTable.RowStriping = 'off';
                app.MinefieldTable.Data(row,col) = 10;
                app.MinefieldTable.BackgroundColor = [1 0 0; 1 0 0];
                app.MinefieldTable.ForegroundColor = [1 1 1];
                app.MinefieldTable.CellSelectionCallback = '';
                %app.Restart = uibutton(app.Minefield, "Text","restart", "ButtonPushedFcn",StartGame(app,event),"Position", [200 100 100 22]);
                % pause(5);
                % app.UIFigure.Visible = 'on';
            else
            app.MinefieldTable.Data(row,col) = countNeighboringMines(app,app.MinefieldTable.Data,row,col);
            end
            % Handle cell selection as needed
            % disp(['Cell Selected: Row ', num2str(row), ', Column ', num2str(col)]);
            
        end
        function count = countNeighboringMines(~, data, row, col)
            count = data(row-1,col-1)+data(row-1,col)+data(row-1,col+1)+data(row,col-1)+data(row,col+1)+data(row+1,col-1)+data(row+1,col)+data(row+1,col+1);
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartGame(app, event)
            difficulty=app.ChoosedifficultyDropDown.Value;
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                case 'Hard'
                    field=zeros(16,30);
                    generateBomb(app,field,99);
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
            
            end
            app.UIFigure.Visible='off';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosedifficultyDropDownLabel = uilabel(app.UIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.UIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end